classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        EsternaButton              matlab.ui.control.StateButton
        TestimagesListBox          matlab.ui.control.ListBox
        TestimagesListBoxLabel     matlab.ui.control.Label
        AccuracyvariClassificatoriButton  matlab.ui.control.Button
        MatricediConfusioneButton  matlab.ui.control.Button
        ClassificaButton           matlab.ui.control.Button
        MostraImmagineButton       matlab.ui.control.Button
        ImmagineEditField          matlab.ui.control.EditField
        ImmagineEditFieldLabel     matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        FinitoLamp                 matlab.ui.control.Lamp
        FinitoLampLabel            matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MostraImmagineButton
        function caricaImmagine(app, event)
            if app.EsternaButton.Value ==0
                app.Image.ImageSource = "risorse/foto/"+app.ImmagineEditField.Value+".jpg";
            else
                app.Image.ImageSource = app.ImmagineEditField.Value;
            end
            app.FinitoLamp.Color = [1 0 0];
        end

        % Button pushed function: ClassificaButton
        function classifica(app, event)
            if app.EsternaButton.Value ==0
                im = imread("risorse/foto/"+app.ImmagineEditField.Value+".jpg");
            else
                im = imread(app.ImmagineEditField.Value);
            end
            
            app.Image.ImageSource = SegmentazioneClassifica(im);
            app.FinitoLamp.Color = [0 1 0];
        end

        % Button pushed function: MatricediConfusioneButton
        function mostraGrafico(app, event)
            app.Image.ImageSource = "risorse/ConfusioneValidation.jpg";
        end

        % Button pushed function: AccuracyvariClassificatoriButton
        function mostraGraficoTrain(app, event)
            app.Image.ImageSource = "risorse/Classificatori.jpg";
        end

        % Value changed function: TestimagesListBox
        function seleziona(app, event)
            value = app.TestimagesListBox.Value;
            app.ImmagineEditField.Value = value;
            app.EsternaButton.Value = 0;
            app.FinitoLamp.Color = [1 0 0];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {742, 742};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {221, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImmagineEditFieldLabel = uilabel(app.LeftPanel);
            app.ImmagineEditFieldLabel.Text = 'Immagine';
            app.ImmagineEditField = uieditfield(app.LeftPanel, 'text');
            app.MostraImmagineButton = uibutton(app.LeftPanel, 'push');
            app.MostraImmagineButton.ButtonPushedFcn = createCallbackFcn(app, @caricaImmagine, true);
            app.MostraImmagineButton.Text = 'Mostra Immagine';
            app.ClassificaButton = uibutton(app.LeftPanel, 'push');
            app.ClassificaButton.ButtonPushedFcn = createCallbackFcn(app, @classifica, true);
            app.ClassificaButton.Text = 'Classifica';
            app.MatricediConfusioneButton = uibutton(app.LeftPanel, 'push');
            app.MatricediConfusioneButton.ButtonPushedFcn = createCallbackFcn(app, @mostraGrafico, true);
            app.MatricediConfusioneButton.Text = 'Matrice di Confusione';
            app.AccuracyvariClassificatoriButton = uibutton(app.LeftPanel, 'push');
            app.AccuracyvariClassificatoriButton.ButtonPushedFcn = createCallbackFcn(app, @mostraGraficoTrain, true);
            app.AccuracyvariClassificatoriButton.Text = 'Accuracy vari Classificatori';
            app.TestimagesListBoxLabel = uilabel(app.LeftPanel);
            app.TestimagesListBoxLabel.Text = 'Test images';
            app.TestimagesListBox = uilistbox(app.LeftPanel);
            app.TestimagesListBox.Items = {'im_oggetto1', 'im_oggetto2', 'im_oggetto3', 'im_oggetto4', 'im_oggetto5', 'im_oggetto6', 'im_oggetto7', 'im_oggetto8', 'im_oggetto9', 'im_oggetto10', 'im_oggetto11', 'im_oggetto12', 'im_oggetto13', 'im_oggetto14', 'im_oggetto15', 'im_oggetto16', 'im_oggetto17', 'im_oggetto18', 'im_oggetto19', 'im_oggetto20', 'im_oggetto21', 'im_oggetto22', 'im_oggetto23', 'im_oggetto24', 'im_oggetto25', 'im_oggetto26', 'im_oggetto27', 'im_oggetto28', 'im_oggetto29', 'im_oggetto30', 'im_group1', 'im_group2', 'im_group3', 'im_group4', 'im_group5', 'im_group6', 'im_group7', 'im_group8', 'im_group9', 'im_group10', 'im_group11', 'im_group12', 'im_group13', 'im_group14', 'im_group15', 'im_group16', 'im_group17', 'im_group18', 'im_group19', 'im_group20', 'im_group21', 'im_group22', 'im_group23', 'im_group24', 'im_group25', 'im_group26', 'im_group27', 'im_group28', 'im_group29', 'im_group30'};
            app.TestimagesListBox.ValueChangedFcn = createCallbackFcn(app, @seleziona, true);
            app.TestimagesListBox.Value = 'im_group3';
            app.EsternaButton = uibutton(app.LeftPanel, 'state');
            app.EsternaButton.Text = 'Esterna';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.FinitoLampLabel = uilabel(app.RightPanel);
            app.FinitoLampLabel.Text = 'Finito';
            app.FinitoLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end